classdef DISC_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Toolbar                   matlab.ui.container.Toolbar
        CircleROI                 matlab.ui.container.toolbar.PushTool
        RectangleROI              matlab.ui.container.toolbar.PushTool
        PolyROI                   matlab.ui.container.toolbar.PushTool
        FreehandROI               matlab.ui.container.toolbar.PushTool
        HUToggleTool              matlab.ui.container.toolbar.ToggleTool
        RightPanel                matlab.ui.container.Panel
        Basis2DropDown            matlab.ui.control.DropDown
        Basis1DropDown            matlab.ui.control.DropDown
        PopoutthisviewButton      matlab.ui.control.Button
        OptimizeDropDown          matlab.ui.control.DropDown
        OptimizeButton            matlab.ui.control.Button
        exportedtoworkstationvariableVolumeLabel  matlab.ui.control.Label
        VolumeButton              matlab.ui.control.Button
        ExporttoWorkstationLabel  matlab.ui.control.Label
        exportedtoworkstationvariableSliceLabel  matlab.ui.control.Label
        SliceButton               matlab.ui.control.Button
        SliceNumberLabel          matlab.ui.control.Label
        SliceSlider               matlab.ui.control.Slider
        SliceField                matlab.ui.control.NumericEditField
        VMEnergykeVLabel          matlab.ui.control.Label
        VMEnergyField             matlab.ui.control.NumericEditField
        AxisDropDown              matlab.ui.control.DropDown
        AxisDropDownLabel         matlab.ui.control.Label
        lowerlimitEditField       matlab.ui.control.NumericEditField
        lowerlimitEditFieldLabel  matlab.ui.control.Label
        upperlimitEditField       matlab.ui.control.NumericEditField
        upperlimitEditFieldLabel  matlab.ui.control.Label
        ImageTypeDropDown         matlab.ui.control.DropDown
        ImageTypeDropDownLabel    matlab.ui.control.Label
        VMEnergySlider            matlab.ui.control.Slider
        LeftPanel                 matlab.ui.container.Panel
        CNRLabel                  matlab.ui.control.Label
        CNRArea                   matlab.ui.control.TextArea
        SNRArea                   matlab.ui.control.TextArea
        SNRLabel                  matlab.ui.control.Label
        NoiseArea                 matlab.ui.control.TextArea
        MeanArea                  matlab.ui.control.TextArea
        StandardDeviationLabel    matlab.ui.control.Label
        MeanLabel                 matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PopupAxesApp = {};
        window_count = 1;

        active_img;
        L_img;
        H_img;
        Z_img;
        C_img;
        energy_array = [20 30 40 50 60 80 100 110 120 130 140 150];
        Al_array = [0.9291, 0.3046, 0.1535, 0.0994, 0.0750, 0.0545, 0.0460, 0.0431, 0.0414, 0.0398, 0.0384, 0.0372];
        PMMA_array=[0.0677, 0.0359, 0.0278, 0.0246, 0.0228, 0.0207, 0.0194, 0.0188, 0.0184, 0.0179, 0.0175, 0.0172];
        W_array =  [0.0810, 0.0376, 0.0268, 0.0227, 0.0206, 0.0184, 0.0171, 0.0166, 0.0161, 0.0157, 0.0154, 0.0151];
        I_array =  [0.2543, 0.0856, 0.2210, 0.1232, 0.0758, 0.0351, 0.0194, 0.0152, 0.0121, 0.0099, 0.0082, 0.0070];
        basis_1_array;
        basis_2_array;
        RED_1;
        RED_2;
        Al_RED = 2.34;
        PMMA_RED = 1.16;
        I_RED = 0.07518;
        W_RED = 1;
        Z_atten;
        C_atten;
        VMenergy;
        LMweight;
        slice_num
        Slice
        Volume
        roi
        bkg_roi
        plot_bkg = false;
        plot_cnr = false;
        roi_1_type = '';
        roi_1_args
        roi_2_type = '';
        roi_2_args
    end
    
    methods (Access = private)
        
        function PlotImage(app)
            delete(app.active_img);
            delete(app.roi);
            delete(app.bkg_roi);
            switch app.ImageTypeDropDown.Value
                case 'Low kV Image'
                    app.Volume = app.L_img;
                case 'High kV Image'
                    app.Volume = app.H_img;
                case 'Basis 1'
                    app.Volume = app.Z_img;
                case 'Basis 2'
                    app.Volume = app.C_img;
                case 'VM'
                    app.Z_atten = spline(app.energy_array, app.basis_1_array, app.VMEnergyField.Value);
                    app.C_atten = spline(app.energy_array, app.basis_2_array, app.VMEnergyField.Value);
                    tmp = app.Z_img.*app.Z_atten + app.C_img.*app.C_atten;
                    c = [-279.6, -23.23, -30430, 0.1101, 2514, -103200, 0.0006911, -21.75, 6439, -0.000004739, 0.06805, -31.56];
                    E = app.VMEnergyField.Value;
                    app.Volume = tmp; %c(1) + c(2)*E + c(3)*tmp + c(4)*E^2 + c(5)*E*tmp + c(6)*tmp.^2 + c(7)*E^3 + c(8)*E^2*tmp + c(9)*E*tmp.^2 + c(10)*E^4 + c(11)*E^3*tmp + c(12)*E^2*tmp.^2;
                case 'Blended'
                    app.Volume = app.H_img * app.LMweight + app.L_img * (1 - app.LMweight);
                case 'RED'
                    app.Volume = app.RED_1*app.Z_img + app.RED_2*app.C_img;
                case 'Zeff'
                    app.Volume = app.Z_img + app.C_img;
            end
            switch app.AxisDropDown.Value
                case 'X'
                    app.Slice = squeeze(app.Volume(app.SliceField.Value,:,:));
                case 'Y'
                    app.Slice = squeeze(app.Volume(:,app.SliceField.Value,:));
                case 'Z'
                    app.Slice = squeeze(app.Volume(:,:,app.SliceField.Value));
            end
            app.active_img = imagesc(app.UIAxes,app.Slice);
            app.UIAxes.CLim = [app.lowerlimitEditField.Value app.upperlimitEditField.Value];
            title(app.UIAxes, app.ImageTypeDropDown.Value);
            app.redrawROIs();
        end

        function [hu, noise] = computeROI(app, roi)
            m = createMask(roi, app.Slice);
            hu = mean2(app.Slice(logical(m)));
            noise = std2(app.Slice(logical(m)));
        end

        function dispROI(app, roi)
            [hu, noise] = app.computeROI(roi);
            app.MeanArea.Value = num2str(hu);
            app.NoiseArea.Value = num2str(noise);
            app.SNRArea.Value = num2str(hu/noise);
        end

        function cnr = computeCNR(app)
            s = createMask(app.roi, app.Slice);
            hu_s = mean2(app.Slice(logical(s)));
            noise_s = std2(app.Slice(logical(s)));

            b = createMask(app.bkg_roi, app.Slice);
            hu_b = mean2(app.Slice(logical(b)));
            noise_b = std2(app.Slice(logical(b)));

            cnr = abs(hu_s - hu_b)/sqrt(0.5*(noise_s^2 + noise_b^2));
        end

        function dispCNR(app)
            cnr = app.computeCNR();
            app.CNRArea.Value = num2str(cnr);
        end

        function clicked(app, roi, evt)
            app.dispROI(roi);
            if (app.plot_cnr)
                app.dispCNR();
            end
        end

        function moved(app, roi, evt)
            app.dispROI(roi);
            if (app.plot_cnr)
                app.dispCNR();
            end
            switch app.roi_1_type
                case 'Cir'
                    app.roi_1_args(1,:) = app.roi.Center;
                    app.roi_1_args(2,:) = app.roi.Radius;
                otherwise
                    app.roi_1_args = app.roi.Position;
            end
            switch app.roi_2_type
                case 'Cir'
                    app.roi_2_args(1,:) = app.bkg_roi.Center;
                    app.roi_2_args(2,:) = app.bkg_roi.Radius;
                otherwise
                    app.roi_2_args = app.bkg_roi.Position;
            end
        end

        function redrawROIs(app)
            switch app.roi_1_type
                case ''
                    return;
                case 'Cir'
                    app.roi = drawcircle(app.UIAxes, Center=app.roi_1_args(1,:), Radius=app.roi_1_args(2,1));
                case 'Rect'
                    app.roi = drawrectangle(app.UIAxes, Position=app.roi_1_args);
                otherwise
                    app.roi = drawpolygon(app.UIAxes, Position=app.roi_1_args);
            end
            addlistener(app.roi, 'MovingROI', @app.moved);
            addlistener(app.roi, 'ROIClicked', @app.clicked);
            switch app.roi_2_type
                case ''
                    return;
                case 'Cir'
                    app.bkg_roi = drawcircle(app.UIAxes, Center=app.roi_2_args(1,:), Radius=app.roi_2_args(2,1));
                case 'Rect'
                    app.bkg_roi = drawrectangle(app.UIAxes, Position=app.roi_2_args);
                otherwise
                    app.bkg_roi = drawpolygon(app.UIAxes, Position=app.roi_2_args);
            end
            addlistener(app.bkg_roi, 'MovingROI', @app.moved);
            addlistener(app.bkg_roi, 'ROIClicked', @app.clicked);
            app.dispROI(app.roi);
            if (app.plot_cnr)
                app.dispCNR();
            end
        end
        
        function result = ConvertToHU(app, input, E)
            c = [-520, -5.863, 325.6, -0.01736, 775];
            result = c(1) + c(2)*input + c(3)*E + c(4)*input.^2 + c(5)*E*input;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VMenergy = 60;
            app.LMweight = 0.5;
            app.Z_img = evalin('base', 'imgAl_asd');
            app.C_img = evalin('base', 'imgPMMA_asd');
            app.L_img = evalin('base', 'imgL_ASD');
            app.H_img = evalin('base','imgH_ASD');
            app.basis_1_array = app.Al_array;
            app.basis_2_array = app.PMMA_array;
            app.RED_1 = app.Al_RED;
            app.RED_2 = app.PMMA_RED;
            app.slice_num = floor([size(app.Z_img, 1) size(app.Z_img, 2) size(app.Z_img, 3)]./2);
            app.SliceField.Limits = [1 size(app.Z_img, 3)];
            app.SliceField.Value = app.slice_num(3);
            app.SliceSlider.Limits = [1 size(app.Z_img, 3)];
            app.SliceSlider.Value = app.slice_num(3);
            app.PlotImage();
        end

        % Callback function: VMEnergySlider, VMEnergySlider
        function VMEnergykeVSliderValueChanging(app, event)
            changingValue = event.Value;
            app.VMEnergyField.Value = changingValue;
            if (strcmp(app.ImageTypeDropDown.Value, 'VM'))
                app.VMenergy = changingValue;
            elseif (strcmp(app.ImageTypeDropDown.Value, 'Blended'))
                app.LMweight = changingValue;
            end
            app.PlotImage();
            % plot image
        end

        % Value changed function: VMEnergyField
        function VMEnergyFieldValueChanged(app, event)
            value = app.VMEnergyField.Value;
            app.VMEnergySlider.Value = value;
            app.PlotImage();
            if (strcmp(app.ImageTypeDropDown.Value, 'VM'))
                app.VMenergy = value;
            elseif (strcmp(app.ImageTypeDropDown.Value, 'Blended'))
                app.LMweight = value;
            end
        end

        % Value changed function: ImageTypeDropDown
        function ImageTypeDropDownValueChanged(app, event)
            app.VMEnergySlider.Enable = false;
            app.VMEnergyField.Enable = false;
            app.VMEnergykeVLabel.Enable = false;
            app.OptimizeButton.Enable = false;
            value = app.ImageTypeDropDown.Value;
            if (strcmp(value,'VM'))
                app.VMEnergySlider.Enable = true;
                app.VMEnergySlider.Limits = [20 150];
                app.VMEnergySlider.Value = app.VMenergy;
                app.VMEnergyField.Enable = true;
                app.VMEnergyField.RoundFractionalValues = true;
                app.VMEnergyField.Limits = [20 150];
                app.VMEnergyField.Value = app.VMenergy; 
                app.VMEnergykeVLabel.Enable = true;
                app.VMEnergykeVLabel.Text = 'VM energy (keV)';
                app.OptimizeButton.Enable = true;
            elseif strcmp(value,'Blended')
                app.VMEnergySlider.Enable = true;
                app.VMEnergySlider.Limits = [0 1];
                app.VMEnergySlider.Value = app.LMweight;
                app.VMEnergyField.Enable = true;
                app.VMEnergyField.RoundFractionalValues = false;
                app.VMEnergyField.Limits = [0 1];
                app.VMEnergyField.Value = app.LMweight;
                app.VMEnergykeVLabel.Enable = true;
                app.VMEnergykeVLabel.Text = 'Relative Weight (High Energy)';
                app.OptimizeButton.Enable = true;
            end
            app.PlotImage();
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)
            value = app.AxisDropDown.Value;
            switch value
                case 'X'
                    idx = 1;
                case 'Y'
                    idx = 2;
                case 'Z'
                    idx = 3;
            end
            app.SliceSlider.Limits = [1 size(app.Z_img, idx)];
            app.SliceSlider.Value = app.slice_num(idx);
            app.SliceField.Limits = [1 size(app.Z_img, idx)];
            app.SliceField.Value = app.slice_num(idx);
            app.PlotImage();
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SliceField.Value = changingValue;
            switch app.AxisDropDown.Value
                case 'X'
                    app.slice_num(1) = changingValue;
                case 'Y'
                    app.slice_num(2) = changingValue;
                case 'Z'
                    app.slice_num(3) = changingValue;
            end
            app.PlotImage()
        end

        % Value changed function: SliceField
        function SliceFieldValueChanged(app, event)
            value = app.SliceField.Value;
            app.SliceSlider.Value = value;
            switch app.AxisDropDown.Value
                case 'X'
                    app.slice_num(1) = value;
                case 'Y'
                    app.slice_num(2) = value;
                case 'Z'
                    app.slice_num(3) = value;
            end
            app.PlotImage();
        end

        % Value changed function: upperlimitEditField
        function upperlimitEditFieldValueChanged(app, event)
            value = app.upperlimitEditField.Value;
            app.PlotImage();
        end

        % Value changed function: lowerlimitEditField
        function lowerlimitEditFieldValueChanged(app, event)
            value = app.lowerlimitEditField.Value;
            app.PlotImage();
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
            assignin('base', 'Slice', app.Slice);
            app.exportedtoworkstationvariableSliceLabel.Visible = true;
        end

        % Callback function: CircleROI
        function CircleROIClicked(app, event)
            if(~app.plot_bkg)
                delete(app.roi)
                app.roi = drawcircle(app.UIAxes);
                app.dispROI(app.roi);
                addlistener(app.roi, 'MovingROI', @app.moved);
                addlistener(app.roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = true;
                if(app.plot_cnr)
                    app.dispCNR()
                end
                app.roi_1_type = 'Cir';
                app.roi_1_args(1,:) = app.roi.Center;
                app.roi_1_args(2,:) = app.roi.Radius;
            else
                delete(app.bkg_roi)
                app.bkg_roi = drawcircle(app.UIAxes);
                app.dispROI(app.bkg_roi);
                addlistener(app.bkg_roi, 'MovingROI', @app.moved);
                addlistener(app.bkg_roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = false;
                app.plot_cnr = true;
                app.dispCNR();
                app.roi_2_type = 'Cir';
                app.roi_2_args(1,:) = app.bkg_roi.Center;
                app.roi_2_args(2,:) = app.bkg_roi.Radius;
            end
        end

        % Callback function: RectangleROI
        function RectangleROIClicked(app, event)
            if(~app.plot_bkg)
                delete(app.roi)
                app.roi = drawrectangle(app.UIAxes);
                app.dispROI(app.roi);
                addlistener(app.roi, 'MovingROI', @app.moved);
                addlistener(app.roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = true;
                if(app.plot_cnr)
                    app.dispCNR()
                end
                app.roi_1_type = 'Rect';
                app.roi_1_args(1,:) = app.roi.Position;
            else
                delete(app.bkg_roi)
                app.bkg_roi = drawrectangle(app.UIAxes);
                app.dispROI(app.bkg_roi);
                addlistener(app.bkg_roi, 'MovingROI', @app.moved);
                addlistener(app.bkg_roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = false;
                app.plot_cnr = true;
                app.dispCNR();
                app.roi_2_type = 'Rect';
                app.roi_2_args(1,:) = app.bkg_roi.Position;
            end
        end

        % Callback function: FreehandROI
        function FreehandROIClicked(app, event)
            if(~app.plot_bkg)
                delete(app.roi)
                app.roi = drawfreehand(app.UIAxes);
                app.dispROI(app.roi);
                addlistener(app.roi, 'MovingROI', @app.moved);
                addlistener(app.roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = true;
                if(app.plot_cnr)
                    app.dispCNR()
                end
                app.roi_1_type = 'FH';
                app.roi_1_args(1,:) = app.roi.Position;
            else
                delete(app.bkg_roi)
                app.bkg_roi = drawfreehand(app.UIAxes);
                app.dispROI(app.bkg_roi);
                addlistener(app.bkg_roi, 'MovingROI', @app.moved);
                addlistener(app.bkg_roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = false;
                app.plot_cnr = true;
                app.dispCNR();
                app.roi_2_type = 'FH';
                app.roi_2_args(1,:) = app.bkg_roi.Position;
            end
        end

        % Callback function: PolyROI
        function PolyROIClicked(app, event)
            if(~app.plot_bkg)
                delete(app.roi)
                app.roi = drawpolygon(app.UIAxes);
                app.dispROI(app.roi);
                addlistener(app.roi, 'MovingROI', @app.moved);
                addlistener(app.roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = true;
                if(app.plot_cnr)
                    app.dispCNR()
                end
                app.roi_1_type = 'Poly';
                app.roi_1_args(1,:) = app.roi.Position;
            else
                delete(app.bkg_roi)
                app.bkg_roi = drawpolygon(app.UIAxes);
                app.dispROI(app.bkg_roi);
                addlistener(app.bkg_roi, 'MovingROI', @app.moved);
                addlistener(app.bkg_roi, 'ROIClicked', @app.clicked);
                app.plot_bkg = false;
                app.plot_cnr = true;
                app.dispCNR();
                app.roi_2_type = 'Poly';
                app.roi_2_args(1,:) = app.bkg_roi.Position;
            end
        end

        % Button pushed function: VolumeButton
        function VolumeButtonPushed(app, event)
            assignin('base', 'Volume', app.Volume)
            
            app.exportedtoworkstationvariableVolumeLabel.Visible = true;
            
            app.exportedtoworkstationvariableVolumeLabel.Visible = false;
            
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            if(strcmp(app.OptimizeDropDown.Value, 'Uniformity'))
                cnr = 99999;
                for ii = app.VMEnergySlider.Limits(1):app.VMEnergySlider.Limits(2)
                    app.VMEnergyField.Value = ii;
                    app.PlotImage();
                    R0 = drawcircle(Center=[256, 256], Radius=26);
                    R1 = drawcircle(Center=[256, 370], Radius=26);
                    R2 = drawcircle(Center=[370, 256], Radius=26);
                    R3 = drawcircle(Center=[142, 256], Radius=26);
                    R4 = drawcircle(Center=[256, 142], Radius=26);
                
                    r0 = app.computeROI(R0);
                    r1 = app.computeROI(R1);
                    r2 = app.computeROI(R2);
                    r3 = app.computeROI(R3);
                    r4 = app.computeROI(R4);
                    a = mean([r0, r1, r2, r3, r4]);
                    tmp = max(abs(a-[r0, r1, r2, r3, r4]));
                    if ii == app.VMEnergySlider.Limits(1)
                        cnr = tmp;
                        BestEnergy = ii;
                        cnr_array(1) = tmp;
                        continue;
                    end
                    if (tmp < cnr)
                        cnr = tmp;
                        BestEnergy = ii;
                    end
                    cnr_array = cat(1, cnr_array, tmp);
                end
            end
            if(~app.plot_cnr && ~strcmp(app.OptimizeDropDown.Value, 'Uniformity'))
                return;
            end
            if(strcmp(app.OptimizeDropDown.Value,'Minimize Contrast'))
                cnr = 99999;
                for ii = app.VMEnergySlider.Limits(1):app.VMEnergySlider.Limits(2)
                    app.VMEnergyField.Value = ii;
                    app.PlotImage();
                    hu1 = app.computeROI(app.roi);
                    hu2 = app.computeROI(app.bkg_roi);
                    tmp = abs(hu1 - hu2);
                    if ii == app.VMEnergySlider.Limits(1)
                        cnr = tmp;
                        BestEnergy = ii;
                        cnr_array(1) = tmp;
                        continue;
                    end
                    if (tmp < cnr)
                        cnr = tmp;
                        BestEnergy = ii;
                    end
                    cnr_array = cat(1, cnr_array, tmp);
                end
            end
            if(strcmp(app.OptimizeDropDown.Value,'Maximize CNR'))
                cnr = 0;
                for ii = app.VMEnergySlider.Limits(1):app.VMEnergySlider.Limits(2)
                    app.VMEnergyField.Value = ii;
                    app.PlotImage();
                    tmp = app.computeCNR();
                    if (tmp > cnr)
                        cnr = tmp;
                        BestEnergy = ii;
                    end
                    if ii == app.VMEnergySlider.Limits(1)
                        cnr_array(1) = tmp;
                        continue;
                    end
                    cnr_array = cat(1, cnr_array, tmp);
                end
            end
            app.VMEnergyField.Value = BestEnergy;
            app.VMEnergySlider.Value = BestEnergy;
            app.PlotImage();
            app.PopupAxesApp{app.window_count} = popupAxes(app, app.VMEnergySlider.Limits(1):app.VMEnergySlider.Limits(2),cnr_array);
            if(strcmp(app.OptimizeDropDown.Value, 'Maximize CNR'))
                app.PopupAxesApp(app.window_count).UIAxes.YLabelString = "CNR";
                app.PopupAxesApp(app.window_count).UIAxes.TitleString = "CNR";
            end
            if(strcmp(app.OptimizeDropDown.Value, 'Minimize Contrast'))
                app.PopupAxesApp(app.window_count).UIAxes.YLabelString = "Difference (HU)";
                app.PopupAxesApp(app.window_count).UIAxes.TitleString = "Contrast";
            end
            app.window_count = app.window_count + 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            while (~isempty(app.PopupAxesApp))
                delete(app.PopupAxesApp{end});
                app.PopupAxesApp(end) = [];
            end
            delete(app);
            
        end

        % Button pushed function: PopoutthisviewButton
        function PopoutthisviewButtonPushed(app, event)
            app.PopupAxesApp{app.window_count} = popupAxes(app, app.Slice);
            app.window_count = app.window_count + 1;
        end

        % Value changed function: Basis1DropDown
        function Basis1DropDownValueChanged(app, event)
            material_1 = app.Basis1DropDown.Value;
            material_2 = app.Basis2DropDown.Value;
            if strcmp(material_2, material_1)
                error('Basis materials must be different!');
            end
            
            tmp = app.basis_1_array;

            E1 = find(app.energy_array == 60);
            E2 = find(app.energy_array == 100);

            switch material_1
                case 'Aluminum'
                    app.basis_1_array = app.Al_array;
                    app.RED_1 = app.Al_RED;
                case 'PMMA'
                    app.basis_1_array = app.PMMA_array;
                    app.RED_1 = app.PMMA_RED;
                case 'Water'
                    app.basis_1_array = app.W_array;
                    app.RED_1 = app.W_RED;
                case 'Iodine'
                    app.basis_1_array = app.I_array;
                    app.RED_1 = app.I_RED;
            end

            old = [[tmp(E1) app.basis_2_array(E1)];[tmp(E2) app.basis_2_array(E2)]];
            new = [[app.basis_1_array(E1) app.basis_2_array(E1)];[app.basis_1_array(E2) app.basis_2_array(E2)]];

            convert = new\old;
            old_Z = app.Z_img;
            old_C = app.C_img;

            app.Z_img = convert(1,1)*old_Z + convert(1,2)*old_C;
            app.C_img = convert(2,1)*old_Z + convert(2,2)*old_C;
            app.PlotImage()
        end

        % Value changed function: Basis2DropDown
        function Basis2DropDownValueChanged(app, event)
            material_1 = app.Basis1DropDown.Value;
            material_2 = app.Basis2DropDown.Value;
            if strcmp(material_2, material_1)
                error('Basis materials must be different!');
            end
            
            tmp = app.basis_2_array;

            E1 = find(app.energy_array==40);
            E2 = find(app.energy_array == 150);

            switch material_2
                case 'Aluminum'
                    app.basis_2_array = app.Al_array;
                    app.RED_2 = app.Al_RED;
                case 'PMMA'
                    app.basis_2_array = app.PMMA_array;
                    app.RED_2 = app.PMMA_RED;
                case 'Water'
                    app.basis_2_array = app.W_array;
                    app.RED_2 = app.W_RED;
                case 'Iodine'
                    app.basis_2_array = app.I_array;
                    app.RED_2 = app.I_RED;
            end

            old = [[app.basis_1_array(E1) tmp(E1)];[app.basis_1_array(E2) tmp(E2)]];
            new = [[app.basis_1_array(E1) app.basis_2_array(E1)];[app.basis_1_array(E2) app.basis_2_array(E2)]];

            convert = new\old;
            old_Z = app.Z_img;
            old_C = app.C_img;

            app.Z_img = convert(1,1)*old_Z + convert(1,2)*old_C;
            app.C_img = convert(2,1)*old_Z + convert(2,2)*old_C;
            app.PlotImage()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.CircleROI = uipushtool(app.Toolbar);
            app.CircleROI.ClickedCallback = createCallbackFcn(app, @CircleROIClicked, true);
            app.RectangleROI = uipushtool(app.Toolbar);
            app.RectangleROI.ClickedCallback = createCallbackFcn(app, @RectangleROIClicked, true);
            app.PolyROI = uipushtool(app.Toolbar);
            app.PolyROI.ClickedCallback = createCallbackFcn(app, @PolyROIClicked, true);
            app.FreehandROI = uipushtool(app.Toolbar);
            app.FreehandROI.ClickedCallback = createCallbackFcn(app, @FreehandROIClicked, true);
            app.HUToggleTool = uitoggletool(app.Toolbar);
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.CLim = [0 1];
            colormap(app.UIAxes, 'gray')
            app.MeanLabel = uilabel(app.LeftPanel);
            app.MeanLabel.Text = 'Mean';
            app.StandardDeviationLabel = uilabel(app.LeftPanel);
            app.StandardDeviationLabel.Text = 'Standard Deviation';
            app.MeanArea = uitextarea(app.LeftPanel);
            app.MeanArea.Editable = 'off';
            app.NoiseArea = uitextarea(app.LeftPanel);
            app.NoiseArea.Editable = 'off';
            app.SNRLabel = uilabel(app.LeftPanel);
            app.SNRLabel.Text = 'SNR';
            app.SNRArea = uitextarea(app.LeftPanel);
            app.SNRArea.Editable = 'off';
            app.CNRArea = uitextarea(app.LeftPanel);
            app.CNRArea.Editable = 'off';
            app.CNRLabel = uilabel(app.LeftPanel);
            app.CNRLabel.Text = 'CNR';
            app.RightPanel = uipanel(app.UIFigure);
            app.VMEnergySlider = uislider(app.RightPanel);
            app.VMEnergySlider.Limits = [20 150];
            app.VMEnergySlider.ValueChangedFcn = createCallbackFcn(app, @VMEnergykeVSliderValueChanging, true);
            app.VMEnergySlider.ValueChangingFcn = createCallbackFcn(app, @VMEnergykeVSliderValueChanging, true);
            app.VMEnergySlider.Enable = 'off';
            app.VMEnergySlider.Value = 60;
            app.ImageTypeDropDownLabel = uilabel(app.RightPanel);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.RightPanel);
            app.ImageTypeDropDown.Items = {'Low kV Image', 'High kV Image', 'Basis 1', 'Basis 2', 'Blended', 'VM', 'RED', 'Zeff'};
            app.ImageTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageTypeDropDownValueChanged, true);
            app.ImageTypeDropDown.Value = 'Basis 1';
            app.upperlimitEditFieldLabel = uilabel(app.RightPanel);
            app.upperlimitEditFieldLabel.Text = 'upper limit';
            app.upperlimitEditField = uieditfield(app.RightPanel, 'numeric');
            app.upperlimitEditField.Limits = [0 Inf];
            app.upperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @upperlimitEditFieldValueChanged, true);
            app.upperlimitEditField.Value = 2;
            app.lowerlimitEditFieldLabel = uilabel(app.RightPanel);
            app.lowerlimitEditFieldLabel.Text = 'lower limit';
            app.lowerlimitEditField = uieditfield(app.RightPanel, 'numeric');
            app.lowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @lowerlimitEditFieldValueChanged, true);
            app.lowerlimitEditField.Value = -1;
            app.AxisDropDownLabel = uilabel(app.RightPanel);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.RightPanel);
            app.AxisDropDown.Items = {'X', 'Y', 'Z'};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = 'Z';
            app.VMEnergyField = uieditfield(app.RightPanel, 'numeric');
            app.VMEnergyField.Limits = [20 150];
            app.VMEnergyField.RoundFractionalValues = 'on';
            app.VMEnergyField.ValueChangedFcn = createCallbackFcn(app, @VMEnergyFieldValueChanged, true);
            app.VMEnergyField.Enable = 'off';
            app.VMEnergyField.Value = 60;
            app.VMEnergykeVLabel = uilabel(app.RightPanel);
            app.VMEnergykeVLabel.Enable = 'off';
            app.VMEnergykeVLabel.Text = 'VM Energy (keV)';
            app.SliceField = uieditfield(app.RightPanel, 'numeric');
            app.SliceField.Limits = [1 100];
            app.SliceField.RoundFractionalValues = 'on';
            app.SliceField.ValueChangedFcn = createCallbackFcn(app, @SliceFieldValueChanged, true);
            app.SliceField.Value = 20;
            app.SliceSlider = uislider(app.RightPanel);
            app.SliceSlider.Limits = [1 100];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.Value = 20;
            app.SliceNumberLabel = uilabel(app.RightPanel);
            app.SliceNumberLabel.Text = 'Slice Number';
            app.SliceButton = uibutton(app.RightPanel, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Text = 'Slice';
            app.exportedtoworkstationvariableSliceLabel = uilabel(app.RightPanel);
            app.exportedtoworkstationvariableSliceLabel.Visible = 'off';
            app.exportedtoworkstationvariableSliceLabel.Text = {'exported to workstation '; 'variable ''Slice'''};
            app.ExporttoWorkstationLabel = uilabel(app.RightPanel);
            app.ExporttoWorkstationLabel.Text = 'Export to Workstation:';
            app.VolumeButton = uibutton(app.RightPanel, 'push');
            app.VolumeButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeButtonPushed, true);
            app.VolumeButton.Text = 'Volume';
            app.exportedtoworkstationvariableVolumeLabel = uilabel(app.RightPanel);
            app.exportedtoworkstationvariableVolumeLabel.Visible = 'off';
            app.exportedtoworkstationvariableVolumeLabel.Text = {'exported to workstation '; 'variable ''Volume'''};
            app.OptimizeButton = uibutton(app.RightPanel, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Enable = 'off';
            app.OptimizeButton.Text = 'Optimize';
            app.OptimizeDropDown = uidropdown(app.RightPanel);
            app.OptimizeDropDown.Items = {'Maximize CNR', 'HU value', 'Minimize Contrast', 'Uniformity'};
            app.OptimizeDropDown.Value = 'Maximize CNR';
            app.PopoutthisviewButton = uibutton(app.RightPanel, 'push');
            app.PopoutthisviewButton.ButtonPushedFcn = createCallbackFcn(app, @PopoutthisviewButtonPushed, true);
            app.PopoutthisviewButton.Text = 'Pop out this view';
            app.Basis1DropDown = uidropdown(app.RightPanel);
            app.Basis1DropDown.Items = {'Aluminum', 'PMMA', 'Iodine', 'Water'};
            app.Basis1DropDown.ValueChangedFcn = createCallbackFcn(app, @Basis1DropDownValueChanged, true);
            app.Basis1DropDown.Value = 'Aluminum';
            app.Basis2DropDown = uidropdown(app.RightPanel);
            app.Basis2DropDown.Items = {'Aluminum', 'PMMA', 'Iodine', 'Water'};
            app.Basis2DropDown.ValueChangedFcn = createCallbackFcn(app, @Basis2DropDownValueChanged, true);
            app.Basis2DropDown.Value = 'PMMA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DISC_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end