classdef popupAxes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, varargin)
            app.CallingApp = caller;
            if (length(varargin) == 1)
                 imagesc(app.UIAxes, varargin{1});
                 clim(app.UIAxes, [app.CallingApp.lowerlimitEditField.Value, app.CallingApp.upperlimitEditField.Value]);
            end
            if (length(varargin) == 2)
                plot(app.UIAxes, varargin{1}, varargin{2}, LineWidth=3);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Contrast-to-Noise vs E')
            xlabel(app.UIAxes, 'E (keV)')
            ylabel(app.UIAxes, 'CNR')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XLimitMethod = 'tight';
            colormap(app.UIAxes, 'gray')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popupAxes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end